classdef MPU6050_uartUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SerialSwitch     matlab.ui.control.Switch
        SerialState      matlab.ui.control.Lamp
        SerialDataWidth  matlab.ui.control.DropDown
        Label_2          matlab.ui.control.Label
        SerialParity     matlab.ui.control.DropDown
        DropDown_4Label  matlab.ui.control.Label
        SerialStopBit    matlab.ui.control.DropDown
        DropDown_3Label  matlab.ui.control.Label
        SerialBaud       matlab.ui.control.DropDown
        DropDown_2Label  matlab.ui.control.Label
        SerialPortNums   matlab.ui.control.DropDown
        Label            matlab.ui.control.Label
        UIAxes_yaw       matlab.ui.control.UIAxes
        UIAxes_roll      matlab.ui.control.UIAxes
        UIAxes_pitch     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SerialObject % 串口对象

        pitch %俯仰角
        roll %横滚角
        yaw %航向角
        num
    end
    
    methods (Access = private)
        
        function serial_refresh(app)
            value = app.SerialSwitch.Value;
            if(value=='打开')%配置串口
               try
                com_list=serialportlist("all");
                app.SerialPortNums.Items=com_list; 
                delete(app.SerialObject);%删除之前打开的实例对象
                app.SerialState.Color=[0 1 0];
                app.SerialObject=serialport(...
                    app.SerialPortNums.Value,...%设置串口号
                    str2double(app.SerialBaud.Value),...%设置串口波特率
                    'DataBits',str2double(app.SerialDataWidth.Value),...%设置数据位宽
                    'StopBits',str2double(app.SerialStopBit.Value), ...%设置停止位
                    'Parity',app.SerialParity.Value);%设置奇偶校验位
                configureTerminator(app.SerialObject,76);%读取和写入通信的终止符设置为字符L
                configureCallback(app.SerialObject,"terminator",@readSerialData);
               catch
                app.SerialState.Color=[0.5 0.5 0.5];
                app.SerialSwitch.Value='关闭';
                msgbox('串口打开失败');
               end
            elseif(value=='关闭')
                delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                app.SerialState.Color=[0.5 0.5 0.5];
            end
               %串口接收数据的回调函数
               function readSerialData(src,event)
                   cell_data=readline(app.SerialObject); 
%                    assignin("base","cell_data",cell_data);
                   raw_data=cell_data{1};
%                    assignin("base","raw_data",raw_data);
                   flag=raw_data(1);
                   raw_data(1)=[];
                   x=1:1:app.num;
                   switch flag
                       case 'p'
                           app.pitch=[str2double(raw_data) app.pitch(1:app.num-1)];
                           plot(app.UIAxes_pitch,x,app.pitch);
                       case 'r'
                           app.roll=[str2double(raw_data) app.roll(1:app.num-1)];
                           plot(app.UIAxes_roll,x,app.roll);
                       case 'y'
                           app.yaw=[str2double(raw_data) app.yaw(1:app.num-1)];
                           plot(app.UIAxes_yaw,x,app.yaw);
%                        case 'A'
% %                            app.A.Value=raw_data;
%                        case 'B'
% %                            app.B.Value=raw_data;
%                        case 'C'
% %                            app.C.Value=raw_data;
                   end
                end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            com_list=serialportlist("all");
            app.SerialPortNums.Items=com_list;
            app.SerialBaud.Value='9600';
            app.SerialStopBit.Value='1' ;%
            app.SerialParity.Value="none";%无奇偶校验位
            app.SerialSwitch.Value='关闭';%默认关闭状态
            app.SerialState.Color=[0.5,0.5,0.5];%关闭状态时指示灯为绿色
            app.num=400;

            app.pitch=zeros([1 app.num]);
            app.roll=zeros([1 app.num]);
            app.yaw=zeros([1 app.num]);


        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            serial_refresh(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_pitch = uiaxes(app.UIFigure);
            title(app.UIAxes_pitch, '俯仰角 pitch')
            ylabel(app.UIAxes_pitch, 'Y')
            zlabel(app.UIAxes_pitch, 'Z')
            app.UIAxes_pitch.XLim = [0 400];
            app.UIAxes_pitch.YLim = [-90 90];
            app.UIAxes_pitch.YTick = [-90 -45 0 45 90];
            app.UIAxes_pitch.YTickLabel = {'-90'; '-45'; '0'; '45'; '90'};
            app.UIAxes_roll = uiaxes(app.UIFigure);
            title(app.UIAxes_roll, '横滚角 roll')
            ylabel(app.UIAxes_roll, 'Y')
            zlabel(app.UIAxes_roll, 'Z')
            app.UIAxes_roll.XLim = [0 400];
            app.UIAxes_roll.YLim = [-180 180];
            app.UIAxes_roll.YTick = [-180 -90 0 90 180];
            app.UIAxes_yaw = uiaxes(app.UIFigure);
            title(app.UIAxes_yaw, '航向角 yaw')
            ylabel(app.UIAxes_yaw, 'Y')
            zlabel(app.UIAxes_yaw, 'Z')
            app.UIAxes_yaw.XLim = [0 400];
            app.UIAxes_yaw.YLim = [-180 180];
            app.UIAxes_yaw.YTick = [-180 -90 0 90 180];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '串口号';
            app.SerialPortNums = uidropdown(app.UIFigure);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '波特率';
            app.SerialBaud = uidropdown(app.UIFigure);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '115200';
            app.DropDown_3Label = uilabel(app.UIFigure);
            app.DropDown_3Label.Text = '停止位';
            app.SerialStopBit = uidropdown(app.UIFigure);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.DropDown_4Label = uilabel(app.UIFigure);
            app.DropDown_4Label.Text = '校验位';
            app.SerialParity = uidropdown(app.UIFigure);
            app.SerialParity.Items = {'none', 'odd', 'even'};
            app.SerialParity.Value = 'none';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '数据位';
            app.SerialDataWidth = uidropdown(app.UIFigure);
            app.SerialDataWidth.Items = {'5', '6', '7', '8'};
            app.SerialDataWidth.Value = '8';
            app.SerialState = uilamp(app.UIFigure);
            app.SerialSwitch = uiswitch(app.UIFigure, 'slider');
            app.SerialSwitch.Items = {'关闭', '打开'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPU6050_uartUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end